classdef controls_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KUKAYouBotTeleoperationMenuLabel  matlab.ui.control.Label
        MotionControlsLabel             matlab.ui.control.Label
        GripperControlsLabel            matlab.ui.control.Label
        VelocityControlmsLabel          matlab.ui.control.Label
        GripperMountedVisionSensorLabel  matlab.ui.control.Label
        ForwardFacingVisionSensorLabel_2  matlab.ui.control.Label
        ForwardFacingVisionSensorLabel  matlab.ui.control.Label
        BaseLabel                       matlab.ui.control.Label
        WristRotateLabel                matlab.ui.control.Label
        WristPitchLabel                 matlab.ui.control.Label
        ElbowLabel                      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        BaseSlider                      matlab.ui.control.Slider
        WristRotateSlider               matlab.ui.control.Slider
        WristPitchSlider                matlab.ui.control.Slider
        ElbowSlider_2                   matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        ConnectionstatusLamp            matlab.ui.control.Lamp
        ConnectionstatusLampLabel       matlab.ui.control.Label
        VelocitySlider                  matlab.ui.control.Slider
        ShoulderLabel                   matlab.ui.control.Label
        RotateLeftButton                matlab.ui.control.Button
        RotateRightButton               matlab.ui.control.Button
        ClosegripperButton              matlab.ui.control.Button
        OpengripperButton               matlab.ui.control.Button
        BrakeButton                     matlab.ui.control.Button
        EmergencyStopButton             matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        BackwardButton                  matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        gripperVisionAxes               matlab.ui.control.UIAxes
        backwardVisionAxes              matlab.ui.control.UIAxes
        forwardVisionAxes               matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        sim            % remoteApi object
        clientID       % Connection ID
        jointHandles   % Struct() storing the joint handles
        visionSensorHandles % Struct() storing the vision sensor handles
        movementSpeed  double = 0 % This will store the slider value
        visionTimer
        sliderTimer
    end
    
    methods (Access = private)
        function getJointHandles(app, jointNames)
            for i = 1:numel(jointNames)
                % Get the joint name
                jointName = jointNames{i};
        
                % Get the joint name from CoppeliaSim
                [~, handle] = app.sim.simxGetObjectHandle(app.clientID, ...
                    jointName, ...
                    app.sim.simx_opmode_oneshot_wait);
                
                 % Store the handle in app.jointHandles struct() using the 
                 % joint name as the field name
                app.jointHandles.(jointName) = handle;
            end
        end

        function updateUISliders(app)
            % A struct() with the joint names and their limits taken from
            % CopelliaSim
            jointsWithLimits = struct ( ...
                'youBotArmJoint0', [-169, 338], ...
                'youBotArmJoint1', [-90, 165], ...
                'youBotArmJoint2', [-131, 262], ...
                'youBotArmJoint3', [-102, 204], ...
                'youBotArmJoint4', [-90, 180] ...
                );

            jointNames = fieldnames(jointsWithLimits);

            % If the joint has predefined limits then configure them
            % into the UI components
            for i = 1:numel(jointNames)
                jointName = jointNames{i};
                limits = jointsWithLimits.(jointName);
                
                % Get the joint handle
                handle = app.jointHandles.(jointName);

                % Get the current position of the join from CoppeliaSim in
                % radians
                [res, currentPosRadians] = app.sim.simxGetJointPosition(app.clientID, ...
                    handle, ...
                    app.sim.simx_opmode_blocking);
    
                if res ~= 0 || isnan(currentPosRadians) || isinf(currentPosRadians)
                    fprintf('Warning: joint %s returned invalid data (res=%d).\n', jointName, res);
                    continue;
                end

                % Convert the value from radians to degrees
                currentPosDegrees = double(rad2deg(currentPosRadians));

                % Extract min and max value limits from the jointsWithLimits
                % struct() arrays
                minVal = limits(1);
                maxVal = limits(2);
                clampedPos = max(min(currentPosDegrees, maxVal), minVal);

                fprintf('Joint: %s, rad=%.2f, deg=%.2f, min=%.2f, max=%.2f, clamped=%.2f\n',...
                jointName, currentPosRadians, currentPosDegrees, minVal, maxVal, clampedPos);

                if strcmp(jointName, 'youBotArmJoint0') && isprop(app, 'BaseSlider')
                    app.BaseSlider.Limits = [minVal, maxVal];
                    app.BaseSlider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint1') && isprop(app, 'ShoulderSlider')
                    app.Slider.Limits = [minVal, maxVal];
                    app.Slider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint2') && isprop(app, 'ElbowSlider_2')
                    app.ElbowSlider_2.Limits = [minVal, maxVal];
                    app.ElbowSlider_2.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint3') && isprop(app, 'WristPitchSlider')
                    app.WristPitchSlider.Limits = [minVal, maxVal];
                    app.WristPitchSlider.Value = clampedPos;
    
                elseif strcmp(jointName, 'youBotArmJoint4') && isprop(app, 'WristRotateSlider')
                    app.WristRotateSlider.Limits = [minVal, maxVal];
                    app.WristRotateSlider.Value = clampedPos;
                end

            end   
        end

        function moveBotForwardBackward(app, velocity)
            % Set the velocity for the right rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_rl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the right front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fr, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
        
            % Set the velocity for the left front rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
            app.jointHandles.rollingJoint_fl, ...
            velocity, ...
            app.sim.simx_opmode_streaming);
    
            fprintf('Robot moving at speed %d\n', velocity);
        end

        function moveSideways(app, velocity)
            % Set the velocity for the front rear rolling joint
            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fr, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_fl, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rr, ...
                velocity, ...
                app.sim.simx_opmode_streaming);

            app.sim.simxSetJointTargetVelocity(app.clientID, ...
                app.jointHandles.rollingJoint_rl, ...
                -velocity, ...
                app.sim.simx_opmode_streaming);

    
            fprintf('Robot moving sideways at speed %d\n', velocity);
        end

        function handleArmJoint(app, jointHandle, angle)
            app.sim.simxSetJointTargetPosition(app.clientID, ...
                jointHandle, ...
                angle, ...
                app.sim.simx_opmode_streaming);
            
            fprintf('Joint moved to %.2f radians\n', angle);
        end

        function smoothSetJointTargetPosition(app, jointHandle, targetPos)
            % Get the current position (blocking call)
            [~, currentPos] = app.sim.simxGetJointPosition(app.clientID, jointHandle, app.sim.simx_opmode_blocking);
            
            % Define smoothing parameters:
            numSteps = 50;         % Number of incremental steps
            dt = 0.02;             % Pause time between steps (seconds)
            stepSize = (targetPos - currentPos) / numSteps;
            
            for i = 1:numSteps
                newPos = currentPos + i * stepSize;
                app.sim.simxSetJointTargetPosition(app.clientID, jointHandle, newPos, app.sim.simx_opmode_oneshot);
                pause(dt);
            end
        end

        function updateVision(app)
            % Ensure client is connected and vision sensor handle is valid
            if app.clientID < 0 || isempty(app.visionSensorHandles.forwardVisionSensor)
                disp('Vision sensor handle is invalid or not initialized.');
                return;
            end
        
            % Retrieve the latest streamed image from CoppeliaSim
            [resForward, ~, imageForwardSensor] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                app.visionSensorHandles.forwardVisionSensor, ...
                0, ...  % Get RGB image
                app.sim.simx_opmode_buffer); % Fetch latest image
            [resBackward, ~, imagebackwardSensor] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                app.visionSensorHandles.backwardVisionSensor, ...
                0, ...  % Get RGB image
                app.sim.simx_opmode_buffer); % Fetch latest image
            [resGripper, ~, imageGripperSensor] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                app.visionSensorHandles.gripperVisionSensor, ...
                0, ...  % Get RGB image
                app.sim.simx_opmode_buffer); % Fetch latest image
        
            % Check if the image retrieval was successful
            if resForward ~= 0
                disp(['Failed to retrieve vision sensor image. Error code: ', num2str(res)]);
                return;
            end
        
            imshow(imageForwardSensor, 'Parent', app.forwardVisionAxes);
            imshow(imagebackwardSensor, 'Parent', app.backwardVisionAxes);
            imshow(imageGripperSensor, 'Parent', app.gripperVisionAxes);

            drawnow;

            % Display the image inside forwardVisionAxes
            % if isvalid(app.forwardVisionAxes)
            %    imshow(backwardVisionSensor, 'Parent', app.forwardVisionAxes);
            %    drawnow;
            % else
            %    disp('UIAxes is invalid or deleted.');
            % end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sim = remApi('remoteApi');
            % To close any existing connections
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1', 19000, true, true, 5000, 5);
    
            % Initialize a struct() to store the KUKA YouBot joints
            app.jointHandles = struct();
            
            % A definition of all joint names
            jointNames = { ...
                'rollingJoint_rr', 'rollingJoint_rl', 'rollingJoint_fr', 'rollingJoint_fl', ...
                'slippingJoint_rr', 'slippingJoint_rl', 'joint', 'slippingJoint_fl', 'slippingJoint_fr', ...
                'youBotArmJoint0', 'youBotArmJoint1', 'youBotArmJoint2', ...
                'youBotArmJoint3', 'youBotArmJoint4', 'youBotGripperJoint1', 'youBotGripperJoint2' ...
            };
            
            app.visionSensorHandles = struct();
    
            if app.clientID > -1
                disp('Connected to CoppeliaSim');
                
                getJointHandles(app, jointNames);

                % Front vision sensor
                [~, app.visionSensorHandles.forwardVisionSensor] = app.sim.simxGetObjectHandle(app.clientID, ...
                    'visionSensor', ...
                    app.sim.simx_opmode_oneshot_wait);
                [~, app.visionSensorHandles.backwardVisionSensor] = app.sim.simxGetObjectHandle(app.clientID, ...
                    'visionSensorBackward', ...
                    app.sim.simx_opmode_oneshot_wait);
                [~, app.visionSensorHandles.gripperVisionSensor] = app.sim.simxGetObjectHandle(app.clientID, ...
                    'gripperVisionSensor', ...
                    app.sim.simx_opmode_oneshot_wait);
          
                [~, ~, ~] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                    app.visionSensorHandles.forwardVisionSensor, ...
                    0, ...
                    app.sim.simx_opmode_streaming); % Start streaming mode
                [~, ~, ~] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                    app.visionSensorHandles.backwardVisionSensor, ...
                    0, ...
                    app.sim.simx_opmode_streaming); % Start streaming mode
                [~, ~, ~] = app.sim.simxGetVisionSensorImage2(app.clientID, ...
                    app.visionSensorHandles.gripperVisionSensor, ...
                    0, ...
                    app.sim.simx_opmode_streaming); % Start streaming mode

                app.visionTimer = timer('ExecutionMode', ...
                'fixedSpacing', ...
                'Period', 0.5, ... % Image updates every 0.5 seconds
                'TimerFcn', @(~,~) updateVision(app));

                app.sliderTimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 1, ... % update every second (adjust as needed)
                'TimerFcn', @(~,~) updateUISliders(app)); % Update the UI slider components with the correct values
                
            
                start(app.visionTimer);
                start(app.sliderTimer);

                % Lamp is green if there is a connection
                app.ConnectionstatusLamp.Color = [0 1 0];                
            else
                disp('Connection failed');

                % Lamp is red if there is a no connection
                app.ConnectionstatusLamp.Color = [1 0 0];
            end
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            app.movementSpeed = app.VelocitySlider.Value;
            disp(['Slider value set to: ', num2str(app.movementSpeed)]);
            
        end

        % Callback function: ForwardButton, UIFigure
        function moveBotForward(app, event)
            app.moveBotForwardBackward(app.movementSpeed);
        end

        % Button pushed function: BackwardButton
        function moveBotBackward(app, event)
            app.moveBotForwardBackward(-app.movementSpeed);
        end

        % Button pushed function: RightButton
        function moveBotRight(app, event)
            app.moveSideways(app.movementSpeed);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.moveSideways(-app.movementSpeed);
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStop(app, event)
            app.moveBotForwardBackward(0);
        end

        % Button pushed function: BrakeButton
        function Brake(app, event)
            desiredVelocity = 0; % Target velocity (fully stopped)
            currentVelocity = app.movementSpeed; % Initial velocity
    
            Kp = 0.5; % Proportional gain (adjust as needed)
    
            % Preserve the direction of motion (sign of the velocity)
            direction = sign(currentVelocity); % +1 (forward), -1 (backward), etc.
    
            while abs(currentVelocity - desiredVelocity) > 0.05
                error = desiredVelocity - currentVelocity;
                controlSignal = Kp * error; % Apply proportional control
                
                % Ensure braking maintains the correct movement direction
                currentVelocity = currentVelocity + direction * controlSignal; 
    
                % Apply the adjusted velocity to all wheels
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rl, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fr, currentVelocity, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fl, currentVelocity, app.sim.simx_opmode_streaming);
    
                pause(0.1);
            end
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            value = app.BaseSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint0, radValue);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint1, radValue);
        end

        % Value changed function: ElbowSlider_2
        function ElbowSlider_2ValueChanged(app, event)
            value = app.ElbowSlider_2.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint2, radValue);
        end

        % Value changed function: WristPitchSlider
        function WristPitchSliderValueChanged(app, event)
            value = app.WristPitchSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint3, radValue);
        end

        % Value changed function: WristRotateSlider
        function WristRotateSliderValueChanged(app, event)
            value = app.WristRotateSlider.Value;
            radValue = deg2rad(value); 
            app.handleArmJoint(app.jointHandles.youBotArmJoint4, radValue);
        end

        % Button pushed function: OpengripperButton
        function OpengripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0.04;
            joint2Pos = -0.04; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end

        % Button pushed function: ClosegripperButton
        function ClosegripperButtonPushed(app, event)
            % Desired target positions for opening
            joint1Pos = 0;
            joint2Pos = 0; % For youBotGripperJoint2
        
            % Smoothly move each joint to its target
            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint1, ...
                joint1Pos);

            smoothSetJointTargetPosition(app, ...
                app.jointHandles.youBotGripperJoint2, ...
                joint2Pos);
            disp('Gripper Opened Smoothly.');
        end

        % Button pushed function: RotateRightButton
        function RotateRightButtonPushed(app, event)
            velocity = app.VelocitySlider.Value; % Get the set speed

            % Set velocities for rotation to the right (clockwise)
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rr, -velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rl, velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fr, -velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fl, velocity, app.sim.simx_opmode_streaming);
        end

        % Button pushed function: RotateLeftButton
        function RotateLeftButtonPushed(app, event)
            velocity = app.VelocitySlider.Value; % Get the set speed

            % Set velocities for rotation to the right (clockwise)
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rr, velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_rl, -velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fr, velocity, app.sim.simx_opmode_streaming);
            app.sim.simxSetJointTargetVelocity(app.clientID, app.jointHandles.rollingJoint_fl, -velocity, app.sim.simx_opmode_streaming);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @moveBotForward, true);
            app.forwardVisionAxes = uiaxes(app.UIFigure);
            app.backwardVisionAxes = uiaxes(app.UIFigure);
            app.gripperVisionAxes = uiaxes(app.UIFigure);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotForward, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotBackward, true);
            app.BackwardButton.Text = 'Backward';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @moveBotRight, true);
            app.RightButton.Text = 'Right';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStop, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BrakeButton = uibutton(app.UIFigure, 'push');
            app.BrakeButton.ButtonPushedFcn = createCallbackFcn(app, @Brake, true);
            app.BrakeButton.Text = 'Brake';
            app.OpengripperButton = uibutton(app.UIFigure, 'push');
            app.OpengripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpengripperButtonPushed, true);
            app.OpengripperButton.Text = 'Open gripper';
            app.ClosegripperButton = uibutton(app.UIFigure, 'push');
            app.ClosegripperButton.ButtonPushedFcn = createCallbackFcn(app, @ClosegripperButtonPushed, true);
            app.ClosegripperButton.Text = 'Close gripper';
            app.RotateRightButton = uibutton(app.UIFigure, 'push');
            app.RotateRightButton.ButtonPushedFcn = createCallbackFcn(app, @RotateRightButtonPushed, true);
            app.RotateRightButton.Text = 'Rotate Right';
            app.RotateLeftButton = uibutton(app.UIFigure, 'push');
            app.RotateLeftButton.ButtonPushedFcn = createCallbackFcn(app, @RotateLeftButtonPushed, true);
            app.RotateLeftButton.Text = 'Rotate Left';
            app.ShoulderLabel = uilabel(app.UIFigure);
            app.ShoulderLabel.Text = 'Shoulder';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 0.8];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.ConnectionstatusLampLabel = uilabel(app.UIFigure);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [-90 165];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 32.465;
            app.ElbowSlider_2 = uislider(app.Panel);
            app.ElbowSlider_2.Limits = [-131 262];
            app.ElbowSlider_2.ValueChangedFcn = createCallbackFcn(app, @ElbowSlider_2ValueChanged, true);
            app.WristPitchSlider = uislider(app.Panel);
            app.WristPitchSlider.Limits = [-102 204];
            app.WristPitchSlider.ValueChangedFcn = createCallbackFcn(app, @WristPitchSliderValueChanged, true);
            app.WristRotateSlider = uislider(app.Panel);
            app.WristRotateSlider.Limits = [-90 180];
            app.WristRotateSlider.ValueChangedFcn = createCallbackFcn(app, @WristRotateSliderValueChanged, true);
            app.BaseSlider = uislider(app.Panel);
            app.BaseSlider.Limits = [-999 999];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.ElbowLabel = uilabel(app.UIFigure);
            app.ElbowLabel.Text = 'Elbow';
            app.WristPitchLabel = uilabel(app.UIFigure);
            app.WristPitchLabel.Text = 'Wrist Pitch';
            app.WristRotateLabel = uilabel(app.UIFigure);
            app.WristRotateLabel.Text = 'Wrist Rotate';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.ForwardFacingVisionSensorLabel = uilabel(app.UIFigure);
            app.ForwardFacingVisionSensorLabel.Text = 'Forward Facing Vision Sensor';
            app.ForwardFacingVisionSensorLabel_2 = uilabel(app.UIFigure);
            app.ForwardFacingVisionSensorLabel_2.Text = 'Forward Facing Vision Sensor';
            app.GripperMountedVisionSensorLabel = uilabel(app.UIFigure);
            app.GripperMountedVisionSensorLabel.Text = 'Gripper Mounted Vision Sensor';
            app.VelocityControlmsLabel = uilabel(app.UIFigure);
            app.VelocityControlmsLabel.Text = 'Velocity Control (m/s)';
            app.GripperControlsLabel = uilabel(app.UIFigure);
            app.GripperControlsLabel.Text = 'Gripper Controls';
            app.MotionControlsLabel = uilabel(app.UIFigure);
            app.MotionControlsLabel.Text = 'Motion Controls';
            app.KUKAYouBotTeleoperationMenuLabel = uilabel(app.UIFigure);
            app.KUKAYouBotTeleoperationMenuLabel.Text = 'KUKA YouBot Teleoperation Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controls_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end